classdef GUIV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Assignment3AppUIFigure    matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        OverviewTab               matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        InsertBoxButton           matlab.ui.control.Button
        ReloadBoxButton           matlab.ui.control.Button
        ConveyorLabel             matlab.ui.control.Label
        TableBlockListLabel       matlab.ui.control.Label
        EditAddButton             matlab.ui.control.Button
        UpdateTableButton         matlab.ui.control.Button
        UpdateConveyorButton      matlab.ui.control.Button
        VisionUpdateLabel         matlab.ui.control.Label
        UITable_2                 matlab.ui.control.Table
        UITable_3                 matlab.ui.control.Table
        P1BlockListLabel          matlab.ui.control.Label
        P2BlockListLabel          matlab.ui.control.Label
        Table                     matlab.ui.container.Tab
        TabVidAxes                matlab.ui.control.UIAxes
        GetClickButton            matlab.ui.control.Button
        ConveyorTab               matlab.ui.container.Tab
        ConVidAxes                matlab.ui.control.UIAxes
        SimpleMoveTab             matlab.ui.container.Tab
        XEditFieldLabel           matlab.ui.control.Label
        XTabH                     matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YTabH                     matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZTabH                     matlab.ui.control.NumericEditField
        TabHSend                  matlab.ui.control.Button
        RelativeToTableHomeLabel  matlab.ui.control.Label
        ComplexMoveTab            matlab.ui.container.Tab
        TicTacToeTab              matlab.ui.container.Tab
        PathPlanTab               matlab.ui.container.Tab
        DebugTab                  matlab.ui.container.Tab
        MainTickLampLabel         matlab.ui.control.Label
        MainTickLamp              matlab.ui.control.Lamp
    end


    properties (Access = private)
        
        % Timer used for tick callback fucntion
        tickTimer;
        tickPeriod = 1; % Period of tick timer
        
        % Video objects and handles
        vidTable;               % videoinput handle
        vidConveyor;            % videoinput handle
        TabVidHandle;   % imshow handle
        ConVidHandle;   % imshow handle
        
        % the commandQueue and communications
        queue = [];
        
        socket = 0;
        connectType = 2;
        % 
        watchdogTimer = 0;
        initFlag = 0;
        Disconnected = 0;
        
        errorFlag = 0;
        
        % Toggle set in tick (debug only)
        tickToggle;
        tickToggle2;
    end

    methods (Access = private)
    
        function InitVideoFeeds(app)
            % Init the video feeds
            % Prep video object for manual and start it
            app.vidTable = videoinput('winvideo', 1, 'MJPG_1600x1200');
            %app.vidTable.ReturnedColorSpace = 'rgb';
            triggerconfig(app.vidTable, 'manual');
            start(app.vidTable);
            % Prep axis handle
            video_resolution1 = app.vidTable.VideoResolution;
            nbands1 = app.vidTable.NumberOfBands;
            app.TabVidHandle = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.TabVidAxes);
            
            % Prep video object for manual and start it
            app.vidConveyor = videoinput('winvideo', 2, 'MJPG_1600x1200');
            %app.vidConveyor.ReturnedColorSpace = 'rgb';
            triggerconfig(app.vidConveyor, 'manual');
            start(app.vidConveyor);
            % Prep axis handle
            video_resolution2 = app.vidConveyor.VideoResolution;
            nbands2 = app.vidConveyor.NumberOfBands;
            app.ConVidHandle = imshow(zeros([video_resolution2(2), video_resolution2(1), nbands2]), 'Parent', app.ConVidAxes);
        end
        
        
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        % Tick Callback function (MAIN LOOP)
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function MainTickCallback(app,~,~)
            if (app.initFlag == 0)
                [app.socket, app.connectType] = startConnectionToRobot();
                if (app.connectType == 2) %2 is an error
                    AppClose(app);
                end
                app.initFlag = 1;
            end
            %disp('n')
            
            if (app.initFlag == 1)
%                 Try to send to robot, if it fails ask for restart
                try
%                   [app.queue, out] = sendToRobot(app.queue, app.socket, app.errorFlag);
%                   out
                catch
                    disp('Connection lost');
                    % Popup a connection error
                    response = connectionPopUp();
                    % If response is 0 then close the app
                    if (response == 0)
                        AppClose(app);
                    else
                        % Otherwise reset init flag to reconnect
                        app.initFlag = 0;
                        CloseConnection(app)
                        return; % No point running rest of tick
                    end
                end
                % Stuff to do if we are connected goes here
                %error = 
                %app.queue = sendToRobot(app.queue, app.socket, error);
            end

            % For debugging (flashes lamp on debug page)
            if (app.tickToggle == 1)
                app.MainTickLamp.Color = 'white';
                app.tickToggle = 0;
            else
                app.MainTickLamp.Color = 'green';
                app.tickToggle = 1;
            end
        end
        
        % Hard close app
        function AppClose(app)
            CloseConnection(app);
            StopTimers(app);
            StopVideos(app);
            %CloseFigs(app);
            delete(app);
        end
        
        % Close socket connection
        function CloseConnection(app)
            try
                if app.socket ~= 0
                    closeConnection(app.socket);         
                end
                
            catch
                disp('Socket close failed');
            end
        end
        
        % Function for gracefully kill all timers. (Thanks to the god that is Image Analyst on mathworks forums)
        function StopTimers(app)
            try
            	%fprintf('Entering StopTimer...\n');
            	listOfTimers = timerfindall; % List all timers, just for info.
            	% Get handle to the one timer that we should have.
            	if isempty(listOfTimers)
            		% Exit if there is no timer to turn off.
            		%fprintf('There are no timers to turn off. Leaving StopTimer().\n');
            		return;
            	end
            	% Stop that timer.
            	stop(app.tickTimer);
                delete(app.tickTimer);
            	% Delete all timers from memory.
            	listOfTimers = timerfindall;
            	if ~isempty(listOfTimers)
                        stop(listOfTimers(:));
            		delete(listOfTimers(:));
            	end
            	%fprintf('Left StopTimer and turned off all timers.\n');
            catch ME
            	errorMessage = sprintf('Error in StopTimer().\nThe error reported by MATLAB is:\n\n%s', ME.message);
            	fprintf('%s\n', errorMessage);
            end
        end
        
        % Stop the video by reset
        function StopVideos(app)
            imaqreset;
        end
        
        % Close all figs
        function CloseFigs(app)
            
        end
   end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start the video feeds and prep handles
            try
                InitVideoFeeds(app);
            catch
                disp('Error in init video feed');
            end
            
            % Init the timer for the tick callback function
            app.tickTimer = timer('StartDelay', 5, 'Period', app.tickPeriod,'ExecutionMode', 'fixedRate','TasksToExecute', Inf,'BusyMode','drop');
            app.tickTimer.TimerFcn = @(obj,event)app.MainTickCallback(app);
            start(app.tickTimer);
            
            % Init the tick toggle
            app.tickToggle = 0;
            
            % Init the connection
            
        end

        % Button pushed function: TabHSend
        function TabHSendPushed(app, event)
            disp('M')

            % X,Y,Z relative move to table home
            x = app.XTabH.Value;
            y = app.YTabH.Value;
            z = app.ZTabH.Value;
            disp('A')
            
            command = "FMT7FFF7FFF7FFF";
            app.queue = sendToQueue(app.queue, command);
                        disp('B')

        end

        % Close request function: Assignment3AppUIFigure
        function Assignment3AppUIFigureCloseRequest(app, event)
            AppClose(app);
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            try
                % Get the snapshot
                imageTab = getsnapshot(app.vidTable);
                % Show it in the correct axes
                set(app.TabVidHandle, 'CData', imageTab); 
                % Process the image for blocks
                %processTabImages(imageTab,app.TabPlotHandle,app.TabVidAxesWindow)
                
            catch
                %disp('Error in table video tick');
            end
        end

        % Button pushed function: UpdateConveyorButton
        function UpdateConveyorButtonPushed(app, event)
            % Try disp conveyor video
            try
                % Get the snapshot
                imageCon = getsnapshot(app.vidConveyor);
                % Show it in the correct axes
                set(app.ConVidHandle, 'CData', imageCon);
                % Process the image for boxes
                %processConImages(imageCon,app.ConPlotHandle,app.ConVidAxesWindow)
            catch
                disp('Error in conveyor video tick');
            end
        end

        % Button pushed function: EditAddButton
        function EditAddButtonPushed(app, event)
            AddEditApp;
        end

        % Button pushed function: InsertBoxButton
        function InsertBoxButtonPushed(app, event)
            
        end

        % Button pushed function: GetClickButton
        function GetClickButtonPushed(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Assignment3AppUIFigure = uifigure;
            app.Assignment3AppUIFigure.Name = 'Assignment 3 App';
            app.Assignment3AppUIFigure.CloseRequestFcn = createCallbackFcn(app, @Assignment3AppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.Assignment3AppUIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.UITable = uitable(app.OverviewTab);
            app.UITable.ColumnName = {'Num'; 'X, Y'; 'Theta'; 'Type'; 'BP'};
            app.UITable.RowName = {};
            app.InsertBoxButton = uibutton(app.OverviewTab, 'push');
            app.InsertBoxButton.ButtonPushedFcn = createCallbackFcn(app, @InsertBoxButtonPushed, true);
            app.InsertBoxButton.Text = 'Insert Box';
            app.ReloadBoxButton = uibutton(app.OverviewTab, 'push');
            app.ReloadBoxButton.Text = 'Reload Box';
            app.ConveyorLabel = uilabel(app.OverviewTab);
            app.ConveyorLabel.Text = 'Conveyor';
            app.TableBlockListLabel = uilabel(app.OverviewTab);
            app.TableBlockListLabel.Text = 'Table Block List';
            app.EditAddButton = uibutton(app.OverviewTab, 'push');
            app.EditAddButton.ButtonPushedFcn = createCallbackFcn(app, @EditAddButtonPushed, true);
            app.EditAddButton.Text = 'Edit/Add';
            app.UpdateTableButton = uibutton(app.OverviewTab, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.UpdateConveyorButton = uibutton(app.OverviewTab, 'push');
            app.UpdateConveyorButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateConveyorButtonPushed, true);
            app.UpdateConveyorButton.Text = 'Update Conveyor';
            app.VisionUpdateLabel = uilabel(app.OverviewTab);
            app.VisionUpdateLabel.Text = 'Vision Update';
            app.UITable_2 = uitable(app.OverviewTab);
            app.UITable_2.ColumnName = {'Num'; 'X, Y'; 'Theta'; 'Type'; 'BP'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.OverviewTab);
            app.UITable_3.ColumnName = {'Num'; 'X, Y'; 'Theta'; 'Type'; 'BP'};
            app.UITable_3.RowName = {};
            app.P1BlockListLabel = uilabel(app.OverviewTab);
            app.P1BlockListLabel.Text = 'P1 Block List';
            app.P2BlockListLabel = uilabel(app.OverviewTab);
            app.P2BlockListLabel.Text = 'P2 Block List';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.TabVidAxes = uiaxes(app.Table);
            title(app.TabVidAxes, 'Table')
            app.GetClickButton = uibutton(app.Table, 'push');
            app.GetClickButton.ButtonPushedFcn = createCallbackFcn(app, @GetClickButtonPushed, true);
            app.GetClickButton.Text = 'Get Click';
            app.ConveyorTab = uitab(app.TabGroup);
            app.ConveyorTab.Title = 'Conveyor';
            app.ConVidAxes = uiaxes(app.ConveyorTab);
            title(app.ConVidAxes, 'Conveyor')
            app.SimpleMoveTab = uitab(app.TabGroup);
            app.SimpleMoveTab.Title = 'Simple Move';
            app.XEditFieldLabel = uilabel(app.SimpleMoveTab);
            app.XEditFieldLabel.Text = 'X';
            app.XTabH = uieditfield(app.SimpleMoveTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.SimpleMoveTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YTabH = uieditfield(app.SimpleMoveTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.SimpleMoveTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZTabH = uieditfield(app.SimpleMoveTab, 'numeric');
            app.TabHSend = uibutton(app.SimpleMoveTab, 'push');
            app.TabHSend.ButtonPushedFcn = createCallbackFcn(app, @TabHSendPushed, true);
            app.TabHSend.Text = 'Send';
            app.RelativeToTableHomeLabel = uilabel(app.SimpleMoveTab);
            app.RelativeToTableHomeLabel.Text = 'Relative To Table Home';
            app.ComplexMoveTab = uitab(app.TabGroup);
            app.ComplexMoveTab.Title = 'Complex Move';
            app.TicTacToeTab = uitab(app.TabGroup);
            app.TicTacToeTab.Title = 'Tic Tac Toe';
            app.PathPlanTab = uitab(app.TabGroup);
            app.PathPlanTab.Title = 'Path Plan';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.MainTickLampLabel = uilabel(app.DebugTab);
            app.MainTickLampLabel.Text = 'Main Tick';
            app.MainTickLamp = uilamp(app.DebugTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIV1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Assignment3AppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Assignment3AppUIFigure)
        end
    end
end